classdef SmartHearingAid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        STARTButton               matlab.ui.control.StateButton
        BeamformerButtonGroup     matlab.ui.container.ButtonGroup
        GSCButton                 matlab.ui.control.ToggleButton
        FrostButton               matlab.ui.control.ToggleButton
        SPShiftButton             matlab.ui.control.ToggleButton
        PShiftButton              matlab.ui.control.ToggleButton
        TDButton                  matlab.ui.control.ToggleButton
        ArrayGeometryButtonGroup  matlab.ui.container.ButtonGroup
        UMA8Button                matlab.ui.control.ToggleButton
        UMA16Button               matlab.ui.control.ToggleButton
        MicLinearButton           matlab.ui.control.ToggleButton
        SmartHearingAidLabel      matlab.ui.control.Label
        DOASlider                 matlab.ui.control.Slider
        DOASliderLabel            matlab.ui.control.Label
        Gauge                     matlab.ui.control.SemicircularGauge
    end

    
    properties (Access = private)

        N = 4;                      % Number of sources
        fs = 44100;                  % Sampling frequency [Hz]
        Fs = 10000;                  % Samples per frame
        c = 343;                    % Speed of sound [m/s]
        t_duration = 30;             % Test duration [s] 

        % Model microphone elements with omnidirectional response pattern 
        microphone = phased.OmnidirectionalMicrophoneElement('FrequencyRange',[20 20e3]);

        angSteer            % Angle of arrival from incoming source of interest
        M                   % Number of microphones
        d                   % Spacing between microphones [m]
        array
        beamformer
  
        
    end
    
    methods (Access = private)

        function array = constructArray(app)

            % Construct selected array:
            if (app.MicLinearButton.Value)
                app.M = 4;                  % Number of microphones
                app.d = 0.05;               % Spacing between microphones [m]
                % Uniform linear array - based on ReSpeaker 4-Mic Linear Array Kit for Raspberry Pi
                array = phased.ULA( app.M, app.d, 'Element',app.microphone);
                disp('ULA');
            elseif (app.UMA16Button.Value)
                app.M = 16;                 % Number of microphones
                app.d = 0.042;              % Spacing between microphones [m]
                % Uniform rectangular array - based on UMA-16 
                array = phased.URA( app.M/4, app.d, 'Element',app.microphone);
                disp('URA');
            elseif (app.UMA8Button.Value)
                app.M = 8;                  % Number of microphones
                app.d = 0.045;              % Spacing between microphones [m]
                % Uniform circular array - based on UMA-8
                array = phased.UCA( app.M, app.d, 'Element',app.microphone);
                disp('UCA');
            end
        end
        
        function beamformer = constructBeamformer(app)
            % Construct selected beamformer:
            % Conventional:     
            if (app.TDButton.Value) % Time Delay Beamformer
                beamformer = phased.TimeDelayBeamformer('SensorArray',app.array,...
                'SampleRate',app.fs,'Direction',app.angSteer,'PropagationSpeed',app.c);
                disp('TD');
        
            elseif (app.PShiftButton.Value) % Phase Shift Beamformer
                beamformer = phased.PhaseShiftBeamformer('SensorArray',app.array,...
            'PropagationSpeed',app.c,'Direction',app.angSteer);
                disp('PS');
            elseif (app.SPShiftButton.Value) % Subband Phase Shift Beamformer
                beamformer = phased.SubbandPhaseShiftBeamformer('SensorArray',app.array,'SampleRate',app.fs,...
            'PropagationSpeed',app.c,'Direction',app.angSteer);
                disp('SPS');
            % Optimal and adaptive:
            
            elseif (app.FrostButton.Value) % Frost Beamformer
                beamformer = phased.FrostBeamformer('SensorArray',app.array,'SampleRate',app.fs,...
            'PropagationSpeed',app.c,'FilterLength',20,'Direction',app.angSteer);
                disp('F');
            elseif (app.GSCButton.Value) % GSC Beamformer
                beamformer = phased.GSCBeamformer('SensorArray',app.array,'SampleRate',app.fs,...
            'PropagationSpeed',app.c,'FilterLength',20,'Direction',app.angSteer);
                disp('GSC');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Ang
            app.angSteer = [0;0];
            % Initial microphone array
            app.array =  constructArray(app);

            % Initial beamformer
            app.beamformer = constructBeamformer(app);
        end

        % Value changed function: DOASlider
        function pointToSource(app, event)
            phi = app.DOASlider.Value;
            app.Gauge.Value = phi;

            app.angSteer = [phi;0];
            app.beamformer = constructBeamformer(app);
        end

        % Selection changed function: ArrayGeometryButtonGroup
        function selectArrayGeometry(app, event)
            app.array = constructArray(app);
            release(app.beamformer);
            app.beamformer = constructBeamformer(app);
        end

        % Selection changed function: BeamformerButtonGroup
        function selectBeamformer(app, event)
            app.beamformer = constructBeamformer(app);
        end

        % Value changed function: STARTButton
        function startBeamforming(app, event)
            value = app.STARTButton.Value;
            if (value == true)
                tic;
                % Initialize variables:
                % Incident direction of audio signals - no change in elevation
                ang_1 = [-90; 0];
                ang_2 = [-30; 0];
                ang_3 = [30; 0];
                ang_4 = [90; 0];

                % Simulate a 3-second multichannel signal received by the array
                collector = phased.WidebandCollector('Sensor',app.array,'PropagationSpeed',app.c,...
                    'SampleRate',app.fs,'NumSubbands',1000,'ModulatedInput', false);
                
                % Noise signal with a power of 1e-4 watts to simulate the thermal noise for each sensor
                noisePwr = 1e-4; % noise power
                
                % START SIMULATION:
                
                % Initialize variables
                NSampPerFrame = app.Fs;
                NTSample = app.t_duration*app.fs;
                S = zeros(NTSample,app.M);
                voice_dft = zeros(NTSample,1); % store for SINR improvement
                voice_cleanspeech = zeros(NTSample,1); % store for SINR improvement
                voice_laugh = zeros(NTSample,1); % store for SINR improvement
                
                % Set up audio device writer
                audioWriter = audioDeviceWriter('SampleRate',app.fs, ...
                    'SupportVariableSizeInput', true);
                isAudioSupported = (length(getAudioDevices(audioWriter))>1);
                
                signal_1 = dsp.AudioFileReader('Voice1.wav',...
                'SamplesPerFrame',NSampPerFrame);
                signal_2 = dsp.AudioFileReader('Music&Voice1.wav',...
                'SamplesPerFrame',NSampPerFrame);
                signal_3 = dsp.AudioFileReader('Noise2.wav',...
                'SamplesPerFrame',NSampPerFrame);
                signal_4 = dsp.AudioFileReader('Voice3.wav',...
                'SamplesPerFrame',NSampPerFrame);
                
                % Simulate
                for m = 1:NSampPerFrame:NTSample
                    sig_idx = m:m+NSampPerFrame-1;
                    x1 = 2*signal_1();   
                    x2 = signal_2();               
                    x3 = signal_3(); 
                    x4 = 2*signal_4();
                    temp = collector([x1 x2 x3 x4],...
                        [ang_1 ang_2 ang_3 ang_4]) + ...
                        sqrt(noisePwr)*randn(NSampPerFrame,app.M);
                    if isAudioSupported
                        play(audioWriter,0.5*temp(:,app.N));
                    end
                    S(sig_idx,:) = temp;
                    voice_dft(sig_idx) = x1;
                    voice_cleanspeech(sig_idx) = x2;
                    voice_laugh(sig_idx) = x3;
                end
                
                
                % CONSTRUCT BEAMFORMER:
                signalsource = dsp.SignalSource('Signal',S,...
                'SamplesPerFrame',NSampPerFrame);
                
                cbfOut = zeros(NTSample,1);
                
                for m = 1:NSampPerFrame:NTSample
                    tic;
                    temp = real(app.beamformer(signalsource()));
                    if isAudioSupported
                        play(audioWriter,temp);
                    end
                    toc;
                    cbfOut(m:m+NSampPerFrame-1,:) = temp;
                end

                pause(6);

                release(signal_1);
                release(signal_2);
                release(signal_3);
                release(signal_4);
                release(signalsource);
                release(audioWriter);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Gauge.Limits = [-90 90];
            app.Gauge.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Gauge.MinorTicks = [];
            app.DOASliderLabel = uilabel(app.UIFigure);
            app.DOASliderLabel.Text = 'DOA';
            app.DOASlider = uislider(app.UIFigure);
            app.DOASlider.Limits = [-90 90];
            app.DOASlider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.DOASlider.ValueChangedFcn = createCallbackFcn(app, @pointToSource, true);
            app.SmartHearingAidLabel = uilabel(app.UIFigure);
            app.SmartHearingAidLabel.Text = 'Smart Hearing Aid ';
            app.ArrayGeometryButtonGroup = uibuttongroup(app.UIFigure);
            app.ArrayGeometryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectArrayGeometry, true);
            app.ArrayGeometryButtonGroup.Title = 'Array Geometry';
            app.MicLinearButton = uitogglebutton(app.ArrayGeometryButtonGroup);
            app.MicLinearButton.Text = '4-Mic Linear';
            app.MicLinearButton.Value = true;
            app.UMA16Button = uitogglebutton(app.ArrayGeometryButtonGroup);
            app.UMA16Button.Text = 'UMA-16';
            app.UMA8Button = uitogglebutton(app.ArrayGeometryButtonGroup);
            app.UMA8Button.Text = 'UMA-8';
            app.BeamformerButtonGroup = uibuttongroup(app.UIFigure);
            app.BeamformerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectBeamformer, true);
            app.BeamformerButtonGroup.Title = 'Beamformer';
            app.TDButton = uitogglebutton(app.BeamformerButtonGroup);
            app.TDButton.Text = 'TD';
            app.TDButton.Value = true;
            app.PShiftButton = uitogglebutton(app.BeamformerButtonGroup);
            app.PShiftButton.Text = 'PShift';
            app.SPShiftButton = uitogglebutton(app.BeamformerButtonGroup);
            app.SPShiftButton.Text = 'SPShift';
            app.FrostButton = uitogglebutton(app.BeamformerButtonGroup);
            app.FrostButton.Text = 'Frost';
            app.GSCButton = uitogglebutton(app.BeamformerButtonGroup);
            app.GSCButton.Text = 'GSC';
            app.STARTButton = uibutton(app.UIFigure, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @startBeamforming, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmartHearingAid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end