classdef SmartHearingAidUMA16 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        STARTButton            matlab.ui.control.StateButton
        BeamformerButtonGroup  matlab.ui.container.ButtonGroup
        GSCButton              matlab.ui.control.ToggleButton
        FrostButton            matlab.ui.control.ToggleButton
        SPShiftButton          matlab.ui.control.ToggleButton
        PShiftButton           matlab.ui.control.ToggleButton
        TDButton               matlab.ui.control.ToggleButton
        SmartHearingAidLabel   matlab.ui.control.Label
        DOASlider              matlab.ui.control.Slider
        DOASliderLabel         matlab.ui.control.Label
        Gauge                  matlab.ui.control.SemicircularGauge
    end

    
    properties (Access = private)

        fs = 8000;                  % Sampling frequency [Hz]
        Fs = 1000;                  % Samples per frame
        c = 343;                    % Speed of sound [m/s]
        t_duration = 3;             % Test duration [s]
        M = 16;                     % Number of microphones
        d = 0.042 ;                 % Spacing between microphones [m]

        % Model microphone elements with omnidirectional response pattern 
        microphone = phased.OmnidirectionalMicrophoneElement('FrequencyRange',[20 20e3]);

        angSteer            % Angle of arrival from incoming source of interest
        array
        beamformer
        deviceReader
        audioWriter
  
        
    end
    
    methods (Access = private)

        function beamformer = constructBeamformer(app)
            % Construct selected beamformer:
            % Conventional:     
            if (app.TDButton.Value) % Time Delay Beamformer
                beamformer = phased.TimeDelayBeamformer('SensorArray',app.array,...
                'SampleRate',app.fs,'Direction',app.angSteer,'PropagationSpeed',app.c);
                disp('TD');
        
            elseif (app.PShiftButton.Value) % Phase Shift Beamformer
                beamformer = phased.PhaseShiftBeamformer('SensorArray',app.array,...
            'PropagationSpeed',app.c,'Direction',app.angSteer);
                disp('PS');
            elseif (app.SPShiftButton.Value) % Subband Phase Shift Beamformer
                beamformer = phased.SubbandPhaseShiftBeamformer('SensorArray',app.array,'SampleRate',app.fs,...
            'PropagationSpeed',app.c,'Direction',app.angSteer);
                disp('SPS');
            % Optimal and adaptive:
            
            elseif (app.FrostButton.Value) % Frost Beamformer
                beamformer = phased.FrostBeamformer('SensorArray',app.array,'SampleRate',app.fs,...
            'PropagationSpeed',app.c,'FilterLength',20,'Direction',app.angSteer);
                disp('F');
            elseif (app.GSCButton.Value) % GSC Beamformer
                beamformer = phased.GSCBeamformer('SensorArray',app.array,'SampleRate',app.fs,...
            'PropagationSpeed',app.c,'FilterLength',20,'Direction',app.angSteer);
                disp('GSC');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Ang
            app.angSteer = [0;0];

            % Initial microphone array
            app.array = phased.URA( app.M/4, app.d, 'Element',app.microphone);
            % Set up audio device reader: UMA-16
            app.deviceReader = audioDeviceReader(...
                'Device', 'miniDSP ASIO Driver',...
                'Driver', 'ASIO', ...
                'SampleRate', app.fs, ...
                'NumChannels', 16 ,...
                'OutputDataType','double',...
                'SamplesPerFrame', app.Fs);
            % Set up audio device writer
            app.audioWriter = audioDeviceWriter('SampleRate',app.fs, ...
                'SupportVariableSizeInput', true);

            % Initial beamformer
            app.beamformer = constructBeamformer(app);
        end

        % Value changed function: DOASlider
        function pointToSource(app, event)
            phi = app.DOASlider.Value;
            app.Gauge.Value = phi;

            app.angSteer = [phi;0];
            app.beamformer = constructBeamformer(app);
        end

        % Selection changed function: BeamformerButtonGroup
        function selectBeamformer(app, event)
            app.beamformer = constructBeamformer(app);
        end

        % Value changed function: STARTButton
        function startBeamforming(app, event)
            value = app.STARTButton.Value;
            while (value == true)            
                signalsource =  app.deviceReader();
                beamformedSignal = real(app.beamformer(signalsource()));
                app.audioWriter(beamformedSignal);                        
            end

            release(app.deviceReader);
            release(app.audioWriter);
            release(app.beamformer);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Gauge.Limits = [-90 90];
            app.Gauge.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Gauge.MinorTicks = [];
            app.DOASliderLabel = uilabel(app.UIFigure);
            app.DOASliderLabel.Text = 'DOA';
            app.DOASlider = uislider(app.UIFigure);
            app.DOASlider.Limits = [-90 90];
            app.DOASlider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.DOASlider.ValueChangedFcn = createCallbackFcn(app, @pointToSource, true);
            app.SmartHearingAidLabel = uilabel(app.UIFigure);
            app.SmartHearingAidLabel.Text = 'Smart Hearing Aid ';
            app.BeamformerButtonGroup = uibuttongroup(app.UIFigure);
            app.BeamformerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectBeamformer, true);
            app.BeamformerButtonGroup.Title = 'Beamformer';
            app.TDButton = uitogglebutton(app.BeamformerButtonGroup);
            app.TDButton.Text = 'TD';
            app.TDButton.Value = true;
            app.PShiftButton = uitogglebutton(app.BeamformerButtonGroup);
            app.PShiftButton.Text = 'PShift';
            app.SPShiftButton = uitogglebutton(app.BeamformerButtonGroup);
            app.SPShiftButton.Text = 'SPShift';
            app.FrostButton = uitogglebutton(app.BeamformerButtonGroup);
            app.FrostButton.Text = 'Frost';
            app.GSCButton = uitogglebutton(app.BeamformerButtonGroup);
            app.GSCButton.Text = 'GSC';
            app.STARTButton = uibutton(app.UIFigure, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @startBeamforming, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmartHearingAidUMA16

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end